classdef transfor_matrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.Button
        CheckBox       matlab.ui.control.CheckBox
        CheckBox_2     matlab.ui.control.CheckBox
        Label          matlab.ui.control.Label
        EditField      matlab.ui.control.NumericEditField
        Label_2        matlab.ui.control.Label
        EditField_2    matlab.ui.control.NumericEditField
        Label_3        matlab.ui.control.Label
        Label_4        matlab.ui.control.Label
        Label_5        matlab.ui.control.Label
        v10Label       matlab.ui.control.Label
        Lamp           matlab.ui.control.Lamp
        Lamp_2         matlab.ui.control.Lamp
        PathLabel      matlab.ui.control.Label
        PathEditField  matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            path = app.PathEditField.Value;
            file = dir(fullfile(path,'/*.csv'));      %csvファイルの情報を全部読み取り
            filenames = {file.name};            %csvファイルの名前を取得
            [~,n] = size(filenames);            %csvファイルの個数を数える
            %%
            %すべてのデータを行列変換
            for i = 1 : n
                k = strcat(filenames(i));           %文字列に変換する
                output{i,1} = append(path,'/',k{1,1});                 %名前を付けて
                output{i,2} = readmatrix(output{i,1});     %データを入れる
                f = output{i,2};                      %data中のi行2列のデータを取出す
                f = f(:,3);                         %計測データの3列を取出す
                %%%%%%%%%%%%%%%%%%
                if app.CheckBox.Value == true
                    num = app.EditField.Value;
                    f = reshape(f,num,[]);   %行列変換（num行ｎ列）
                elseif app.CheckBox_2.Value == true
                    num = app.EditField_2.Value;
                    f = reshape(f,[],num);    %行列変換（n行ｎum列）
                end
                %%%%%%%%%%%%%%%%%%
                output{i,2} = f;
                outputname=append(path,'/op_',k{1,1},'.csv');
                writematrix(f,outputname);
            end
% clearvars -except output
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == true
                app.Lamp.Color = [0 1 0];
            else
                app.Lamp.Color = [1 0 0];
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value == true
                app.Lamp_2.Color = [0 1 0];
            else
                app.Lamp_2.Color = [1 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '実行';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '行';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '列';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '実行後';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'に並び替える';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'に並び替える';
            app.v10Label = uilabel(app.UIFigure);
            app.v10Label.Text = '自動一括行列変換 v1.0';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path：';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transfor_matrix

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end